classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        SegmentationTab          matlab.ui.container.Tab
        EdgeImageLabel           matlab.ui.control.Label
        DeteksiTepiButtonGroup   matlab.ui.container.ButtonGroup
        CannyButton              matlab.ui.control.RadioButton
        RobertsButton            matlab.ui.control.RadioButton
        PrewittButton            matlab.ui.control.RadioButton
        SobelButton              matlab.ui.control.RadioButton
        LoGButton                matlab.ui.control.RadioButton
        LaplaceButton            matlab.ui.control.RadioButton
        OutputLabel_2            matlab.ui.control.Label
        CalculateButton          matlab.ui.control.Button
        InputImageButton         matlab.ui.control.Button
        UIAxes_5                 matlab.ui.control.UIAxes
        UIAxes_2                 matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
        HoughTransformationTab   matlab.ui.container.Tab
        EdgeImageLabel_2         matlab.ui.control.Label
        ThresholdEditField       matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel  matlab.ui.control.Label
        qEditField               matlab.ui.control.NumericEditField
        qEditFieldLabel          matlab.ui.control.Label
        pEditField               matlab.ui.control.NumericEditField
        pEditFieldLabel          matlab.ui.control.Label
        OutputLabel              matlab.ui.control.Label
        CalculateButton_2        matlab.ui.control.Button
        InputImageButton_2       matlab.ui.control.Button
        UIAxes_6                 matlab.ui.control.UIAxes
        UIAxes_4                 matlab.ui.control.UIAxes
        UIAxes_3                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ImageInput1 = nan
        ImageInput2 = nan
        deteksitepi = 0
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disableDefaultInteractivity(app.UIAxes)
            disableDefaultInteractivity(app.UIAxes_2)
            disableDefaultInteractivity(app.UIAxes_3)
            disableDefaultInteractivity(app.UIAxes_4)
            disableDefaultInteractivity(app.UIAxes_5)
            disableDefaultInteractivity(app.UIAxes_6)
        end

        % Button pushed function: InputImageButton_2
        function InputImageButton_2Pushed(app, event)
            [filename, pathname] = uigetfile({'*.bmp;*.jpg;*.jpeg;*.png'},"Open File");
            fullpath = strcat(pathname,filename);
            if(fullpath == "")
                warndlg("File Not Found")
            else
                image = imread(fullpath);
                app.ImageInput2 = imread(fullpath);
                imshow(image,'Parent',app.UIAxes_3);
            end
        end

        % Button pushed function: InputImageButton
        function InputImageButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.bmp;*.jpg;*.jpeg;*.png'},"Open File");
            fullpath = strcat(pathname,filename);
            if(fullpath == "")
                warndlg("File Not Found")
            else
                image = imread(fullpath);
                app.ImageInput1 = imread(fullpath);
                imshow(image,'Parent',app.UIAxes);
            end
        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)
            if(isnan(app.ImageInput2))
                warndlg("Must Input Image First", "Input")
            else
                [hasil,edge] = houghtrans(app.ImageInput2,app.pEditField.Value, app.qEditField.Value, app.ThresholdEditField.Value);
                imshow(hasil,'Parent',app.UIAxes_4);
                imshow(edge,'Parent',app.UIAxes_6);
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            if(isnan(app.ImageInput1))    
                warndlg("Must Input Image First", "Input")
            else
                [Hasil, edge] = edge_detection(app.ImageInput1,app.deteksitepi);
                imshow(Hasil,'Parent',app.UIAxes_2);
                imshow(edge,'Parent',app.UIAxes_5);
            end
        end

        % Selection changed function: DeteksiTepiButtonGroup
        function DeteksiTepiButtonGroupSelectionChanged(app, event)
            if(app.LaplaceButton.Value == 1)
                app.deteksitepi = 0;
            elseif(app.LoGButton.Value == 1)
                app.deteksitepi = 1;
            elseif(app.SobelButton.Value == 1)
                app.deteksitepi = 2;
            elseif(app.PrewittButton.Value == 1)
                app.deteksitepi = 3;
            elseif(app.RobertsButton.Value == 1)
                app.deteksitepi = 4;
            elseif(app.CannyButton.Value == 1)
                app.deteksitepi = 5;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SegmentationTab = uitab(app.TabGroup);
            app.SegmentationTab.Title = 'Segmentation';
            app.UIAxes = uiaxes(app.SegmentationTab);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.SegmentationTab);
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_5 = uiaxes(app.SegmentationTab);
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_5.XTick = [];
            app.UIAxes_5.YTick = [];
            app.InputImageButton = uibutton(app.SegmentationTab, 'push');
            app.InputImageButton.ButtonPushedFcn = createCallbackFcn(app, @InputImageButtonPushed, true);
            app.InputImageButton.Text = 'Input Image';
            app.CalculateButton = uibutton(app.SegmentationTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.OutputLabel_2 = uilabel(app.SegmentationTab);
            app.OutputLabel_2.Text = 'Output';
            app.DeteksiTepiButtonGroup = uibuttongroup(app.SegmentationTab);
            app.DeteksiTepiButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DeteksiTepiButtonGroupSelectionChanged, true);
            app.DeteksiTepiButtonGroup.Title = 'Deteksi Tepi';
            app.LaplaceButton = uiradiobutton(app.DeteksiTepiButtonGroup);
            app.LaplaceButton.Text = 'Laplace';
            app.LaplaceButton.Value = true;
            app.LoGButton = uiradiobutton(app.DeteksiTepiButtonGroup);
            app.LoGButton.Text = 'LoG';
            app.SobelButton = uiradiobutton(app.DeteksiTepiButtonGroup);
            app.SobelButton.Text = 'Sobel';
            app.PrewittButton = uiradiobutton(app.DeteksiTepiButtonGroup);
            app.PrewittButton.Text = 'Prewitt';
            app.RobertsButton = uiradiobutton(app.DeteksiTepiButtonGroup);
            app.RobertsButton.Text = 'Roberts';
            app.CannyButton = uiradiobutton(app.DeteksiTepiButtonGroup);
            app.CannyButton.Text = 'Canny';
            app.EdgeImageLabel = uilabel(app.SegmentationTab);
            app.EdgeImageLabel.Text = 'Edge Image';
            app.HoughTransformationTab = uitab(app.TabGroup);
            app.HoughTransformationTab.Title = 'Hough Transformation';
            app.UIAxes_3 = uiaxes(app.HoughTransformationTab);
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_4 = uiaxes(app.HoughTransformationTab);
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.UIAxes_6 = uiaxes(app.HoughTransformationTab);
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_6.XTick = [];
            app.UIAxes_6.YTick = [];
            app.InputImageButton_2 = uibutton(app.HoughTransformationTab, 'push');
            app.InputImageButton_2.ButtonPushedFcn = createCallbackFcn(app, @InputImageButton_2Pushed, true);
            app.InputImageButton_2.Text = 'Input Image';
            app.CalculateButton_2 = uibutton(app.HoughTransformationTab, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = 'Calculate';
            app.OutputLabel = uilabel(app.HoughTransformationTab);
            app.OutputLabel.Text = 'Output';
            app.pEditFieldLabel = uilabel(app.HoughTransformationTab);
            app.pEditFieldLabel.Text = 'p';
            app.pEditField = uieditfield(app.HoughTransformationTab, 'numeric');
            app.qEditFieldLabel = uilabel(app.HoughTransformationTab);
            app.qEditFieldLabel.Text = 'q';
            app.qEditField = uieditfield(app.HoughTransformationTab, 'numeric');
            app.ThresholdEditFieldLabel = uilabel(app.HoughTransformationTab);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.HoughTransformationTab, 'numeric');
            app.EdgeImageLabel_2 = uilabel(app.HoughTransformationTab);
            app.EdgeImageLabel_2.Text = 'Edge Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end